classdef TrackerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        PlayButton                     matlab.ui.control.StateButton
        CovarianceSigmaEditField       matlab.ui.control.NumericEditField
        CovarianceSigmaEditFieldLabel  matlab.ui.control.Label
        ShowCovariancesCheckBox        matlab.ui.control.CheckBox
        ShowAssociationsCheckBox       matlab.ui.control.CheckBox
        ShowMeasurementsCheckBox       matlab.ui.control.CheckBox
        ShowTracksCheckBox             matlab.ui.control.CheckBox
        ShowTruthCheckBox              matlab.ui.control.CheckBox
        ShowLabelsCheckBox             matlab.ui.control.CheckBox
        FrameNumberSpinner             matlab.ui.control.Spinner
        FrameNumberSpinnerLabel        matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        FrameSlider                    matlab.ui.control.Slider
        FrameSliderLabel               matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        % TrackLog is Cell array containing pertinent Time History info 
        % about tracks, measurements, and associations
        % Order is the following columnwise:
        % 1. Trk id's
        % 2. trk states
        % 3. trk covariances
        % 4. measurements values
        % 5. measurement covariances
        % 6. Associations
        TrackLog

        % TruthStates is an array of time-indexed truth states
        % rows correspond to state components
        % columns correspond to individual targets
        % pages correspond to time progression
        TruthStates % Description
        CurrFrame % Description
        CircleCoords % Coordinates of a circle

        FigHandles %All figure handles
    end
    
    methods (Access = private)
        
        function [] = UpdatePlot(app)
            cla(app.UIAxes)
            hold(app.UIAxes,"on");
            if app.ShowTruthCheckBox.Value
                app.PlotTruth();
            end
            if app.ShowTracksCheckBox.Value
                app.PlotTracks();
            end
            if app.ShowMeasurementsCheckBox.Value
                app.PlotObs();
            end
            hold(app.UIAxes,"off");
        end
        
        function [] = PlotTruth(app)
            TargPage = app.TruthStates(:,:,app.CurrFrame);
            Positions = TargPage(1:2,:)';
            Velocities = TargPage(3:4,:)';
            scatter(app.UIAxes,Positions(:,1),Positions(:,2),45,'red','filled','^');
            quiver(app.UIAxes,Positions(:,1),Positions(:,2),Velocities(:,1),Velocities(:,2),"off","filled",Color='r',LineWidth=1.5)
            if app.ShowLabelsCheckBox.Value
                labels = " \leftarrow Target "+string(num2str([1:size(Positions,1)]'));
                text(app.UIAxes,Positions(:,1),Positions(:,2),labels,"FontWeight","bold","FontSize",14);
            end
        end
        
        function [] = PlotTracks(app)
            [Ids,States,Ps,Deaths] = app.TrackLog{app.CurrFrame,[1:3,8]};
            Births = app.TrackLog{max(app.CurrFrame-1,1),7};
            if isempty(Ids)
                return
            end
            Labels = "Trk"+string(num2str(Ids'))+"\rightarrow";
            try
                DeathLabels = "Trk"+string(num2str(Ids(Deaths)'))+" Killed";
                BirthLabels = "Trk"+string(num2str(Ids(Births)'))+" Spawned";
            catch
                DeathLabels = [];
                BirthLabels = [];
            end
            Positions = States(1:2,:)';
            Velocities = States(3:4,:)';
            scatter(app.UIAxes,Positions(:,1),Positions(:,2),30,"white","filled")
            if ~isempty(Births) && all(size(Positions,1)>=Births,"all")
            scatter(app.UIAxes,Positions(Births,1),Positions(Births,2),200,"green","filled","pentagram")
            end
            if ~isempty(Deaths)
            scatter(app.UIAxes,Positions(Deaths,1),Positions(Deaths,2),400,"red","x")
            end
            quiver(app.UIAxes,Positions(:,1),Positions(:,2),Velocities(:,1),Velocities(:,2),"off","filled",Color='w',LineWidth=1.5)
            if app.ShowCovariancesCheckBox.Value
                for n = 1:size(Ps,3)
                    PosP = Ps(1:2,1:2,n);
                    VelP = Ps(3:4,3:4,n);
                    transformedCircPos = app.CovarianceSigmaEditField.Value*(chol(PosP) * app.CircleCoords);
                    plot(app.UIAxes,Positions(n,1)+transformedCircPos(1,:),Positions(n,2)+transformedCircPos(2,:),"Color",'w','LineStyle','--',LineWidth=2)
                    transformedCircVel = app.CovarianceSigmaEditField.Value*(chol(VelP) * app.CircleCoords);
                    plot(app.UIAxes,Positions(n,1)+Velocities(n,1)+transformedCircVel(1,:),Positions(n,2)+Velocities(n,2)+transformedCircVel(2,:),"Color",'w','LineStyle','-',LineWidth=0.5)
                end
            end
            if app.ShowLabelsCheckBox.Value
                text(app.UIAxes,Positions(:,1),Positions(:,2),Labels,"FontWeight","bold","FontSize",14,"HorizontalAlignment","right");
                
                try
                if ~isempty(Births)
                    text(app.UIAxes,Positions(Births,1),Positions(Births,2),BirthLabels,"FontWeight","bold","FontSize",16,"VerticalAlignment","top",...
                        "Color","green")
                end
                if ~isempty(Deaths)
                    text(app.UIAxes,Positions(Deaths,1),Positions(Deaths,2),DeathLabels,"FontWeight","bold","FontSize",16,"VerticalAlignment","bottom",...
                        "Color","red")
                end
                catch
                    warning("Failed to display birth/death label")
                end
            end
        end
        
        function [] = PlotObs(app)
            [Ids,States,Ps,Measurement,R,Assoc] = app.TrackLog{app.CurrFrame,:};
            if ~isempty(Measurement)
            Positions = States(1:2,:);
            Meas = Measurement;
            scatter(app.UIAxes,Meas(1,:),Meas(2,:),30,"magenta","filled");
            if app.ShowCovariancesCheckBox.Value
                for n = 1:size(R,3)
                        PosR = R(:,:,n);
                        transformedCirc = app.CovarianceSigmaEditField.Value*(chol(PosR) * app.CircleCoords);
                        plot(app.UIAxes,Meas(1,n)+transformedCirc(1,:),Meas(2,n)+transformedCirc(2,:),"Color",'m','LineStyle','--',LineWidth=2)
                end
            end
            if app.ShowAssociationsCheckBox.Value
                for a = Assoc'
                    midx = a(2);
                    trkidx = a(1);
                    plot(app.UIAxes,[Meas(1,midx),Positions(1,trkidx)],[Meas(2,midx),Positions(2,trkidx)],...
                        Color='m',LineWidth=2,LineStyle='--')
                end
            end
            end
        end
        
        function [] = setFrame(app,n)
            app.FrameNumberSpinner.Value = n;
            app.FrameSlider.Value = n;
            app.CurrFrame = n;
            app.UpdatePlot();
        end
        
        function app = makeFigureHandles(app)
            app.FigHandles.Targets = plot(app.UIAxes,0,0,Color='r',LineStyle='none',Marker='^',MarkerSize=12,Clipping='off');
            app.FigHandles.TargetLabels = text(app.UIAxes,0,0,"Target Label","FontWeight","bold","HorizontalAlignment","left",Clipping="off",HitTest="off");

            app.FigHandles.Tracks = plot(app.UIAxes,0,0,Color='w',LineStyle='none',Marker='.',MarkerSize=12,Clipping='off');
            app.FigHandles.TrackCov = plot(app.UIAxes,0,0,Color='w',LineStyle='--',LineWidth=2,Clipping='off');
            app.FigHandles.TrackLabels = text(app.UIAxes,0,0,"Trk Label","FontWeight","bold","HorizontalAlignment","right",Clipping="off",HitTest="off");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, TruthStates, TrackLog)
            app.TrackLog = TrackLog;
            app.TruthStates = TruthStates;
            assert(size(app.TrackLog,1)==size(app.TruthStates,3),"TrackLog and TruthStates have different number of frames")
            app.FrameNumberSpinner.Value = 1;
            app.CurrFrame = 1;
            app.FrameNumberSpinner.Limits = [1,size(app.TruthStates,3)];
            app.FrameSlider.Limits = app.FrameNumberSpinner.Limits;
            app.CircleCoords = [cos(linspace(0,2*pi,100));sin(linspace(0,2*pi,100))];
            app.CovarianceSigmaEditField.Value = 3;
            app.FigHandles = struct();
            app.UpdatePlot();
        end

        % Value changed function: ShowTruthCheckBox
        function ShowTruthCheckBoxValueChanged(app, event)
            value = app.ShowTruthCheckBox.Value;
            app.UpdatePlot();
        end

        % Value changed function: ShowLabelsCheckBox
        function ShowLabelsCheckBoxValueChanged(app, event)
            value = app.ShowLabelsCheckBox.Value;
            app.UpdatePlot();
        end

        % Value changed function: ShowTracksCheckBox
        function ShowTracksCheckBoxValueChanged(app, event)
            value = app.ShowTracksCheckBox.Value;
            app.UpdatePlot();
        end

        % Value changed function: FrameNumberSpinner
        function FrameNumberSpinnerValueChanged(app, event)
            value = app.FrameNumberSpinner.Value;
            app.setFrame(value);
            app.UpdatePlot();
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            value = app.FrameSlider.Value;
            app.setFrame(value);
            app.UpdatePlot();
        end

        % Value changed function: ShowCovariancesCheckBox
        function ShowCovariancesCheckBoxValueChanged(app, event)
            value = app.ShowCovariancesCheckBox.Value;
            app.UpdatePlot();
        end

        % Value changed function: CovarianceSigmaEditField
        function CovarianceSigmaEditFieldValueChanged(app, event)
            value = app.CovarianceSigmaEditField.Value;
            app.UpdatePlot();
        end

        % Value changing function: FrameNumberSpinner
        function FrameNumberSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.setFrame(changingValue);
            app.UpdatePlot();
        end

        % Value changed function: ShowMeasurementsCheckBox
        function ShowMeasurementsCheckBoxValueChanged(app, event)
            value = app.ShowMeasurementsCheckBox.Value;
            app.UpdatePlot();
        end

        % Value changed function: ShowAssociationsCheckBox
        function ShowAssociationsCheckBoxValueChanged(app, event)
            value = app.ShowAssociationsCheckBox.Value;
            app.UpdatePlot();
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UpdatePlot();
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if value
                app.setFrame(app.CurrFrame+1);
                app.UpdatePlot();
                pause(0.1);
                PlayButtonValueChanged(app,event);
            else
                app.UpdatePlot();
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {704, 704};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {246, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FrameNumberSpinnerLabel = uilabel(app.LeftPanel);
            app.FrameNumberSpinnerLabel.Text = 'Frame Number';
            app.FrameNumberSpinner = uispinner(app.LeftPanel);
            app.FrameNumberSpinner.ValueChangingFcn = createCallbackFcn(app, @FrameNumberSpinnerValueChanging, true);
            app.FrameNumberSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameNumberSpinnerValueChanged, true);
            app.ShowLabelsCheckBox = uicheckbox(app.LeftPanel);
            app.ShowLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLabelsCheckBoxValueChanged, true);
            app.ShowLabelsCheckBox.Text = 'Show Labels';
            app.ShowLabelsCheckBox.Value = true;
            app.ShowTruthCheckBox = uicheckbox(app.LeftPanel);
            app.ShowTruthCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowTruthCheckBoxValueChanged, true);
            app.ShowTruthCheckBox.Text = 'Show Truth';
            app.ShowTruthCheckBox.Value = true;
            app.ShowTracksCheckBox = uicheckbox(app.LeftPanel);
            app.ShowTracksCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowTracksCheckBoxValueChanged, true);
            app.ShowTracksCheckBox.Text = 'Show Tracks';
            app.ShowTracksCheckBox.Value = true;
            app.ShowMeasurementsCheckBox = uicheckbox(app.LeftPanel);
            app.ShowMeasurementsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowMeasurementsCheckBoxValueChanged, true);
            app.ShowMeasurementsCheckBox.Text = 'Show Measurements';
            app.ShowAssociationsCheckBox = uicheckbox(app.LeftPanel);
            app.ShowAssociationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowAssociationsCheckBoxValueChanged, true);
            app.ShowAssociationsCheckBox.Text = 'Show Associations';
            app.ShowCovariancesCheckBox = uicheckbox(app.LeftPanel);
            app.ShowCovariancesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCovariancesCheckBoxValueChanged, true);
            app.ShowCovariancesCheckBox.Text = 'Show Covariances';
            app.ShowCovariancesCheckBox.Value = true;
            app.CovarianceSigmaEditFieldLabel = uilabel(app.LeftPanel);
            app.CovarianceSigmaEditFieldLabel.Text = 'Covariance Sigma';
            app.CovarianceSigmaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CovarianceSigmaEditField.Limits = [0 Inf];
            app.CovarianceSigmaEditField.ValueChangedFcn = createCallbackFcn(app, @CovarianceSigmaEditFieldValueChanged, true);
            app.CovarianceSigmaEditField.Value = 3;
            app.PlayButton = uibutton(app.LeftPanel, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Track Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FrameSliderLabel = uilabel(app.RightPanel);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.RightPanel);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.Step = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrackerApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end